classdef ambiente_virtual_ya_porfavor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        sQ5             matlab.ui.control.Slider
        sQ5SliderLabel  matlab.ui.control.Label
        sQ4             matlab.ui.control.Slider
        sQ4SliderLabel  matlab.ui.control.Label
        sQ3             matlab.ui.control.Slider
        sQ3SliderLabel  matlab.ui.control.Label
        sQ2             matlab.ui.control.Slider
        sQ2SliderLabel  matlab.ui.control.Label
        sQ1             matlab.ui.control.Slider
        sQ1SliderLabel  matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      % Nodos de transformación (uno por articulación)
    t0; t1; t2; t3; t4; t5;   % hgtransform

    % Geometría STL (patch) de cada eslabón que SÍ tiene malla
    p0; p1; p2; p3; p4;

    % Dimensiones del robot (m)
    L1 = 0.0435;     % d1
    L3 = 0.105;      % a3
    L4 = 0.02693;    % d4

    % Escala para STL: 1e-3 si están en mm; 1 si ya están en metros
    scaleSTL = 1e-3;    % cámbialo a 1e-3 si ves el robot gigante  
                s % objeto serialport
            timerObj % timer para leer datos periódicamente
    end
    
    methods (Access = private)
        
        function h = localLoadSTL(app,filename,parentNode)
            % Lee STL a triangulación y lo escala
            TR = stlread(filename);               % MATLAB R2019a+; si no, usa tu stlread.m
            F  = TR.ConnectivityList;
            V  = TR.Points * app.scaleSTL;

            h = patch('Faces',F,'Vertices',V, ...
            'Parent',parentNode, ...
            'FaceColor',[0.7 0.7 0.75], ...
             'EdgeColor','none', ...
             'FaceLighting','gouraud');
            
        end
        
        function localUpdateRobot(app)
    % Lee sliders (radianes)
    q1 = app.sQ1.Value;
    q2 = app.sQ2.Value;
    q3 = app.sQ3.Value;
    q4 = app.sQ4.Value;
    q5 = app.sQ5.Value;

    % Atajos trigonométricos
    c1=cos(q1); s1=sin(q1);
    c2=cos(q2); s2=sin(q2);
    c3p=cos(q3+pi/2); s3p=sin(q3+pi/2);
    c4=cos(q4); s4=sin(q4);
    c5=cos(q5); s5=sin(q5);

    % Parámetros de tu tabla DH
    d1 = app.L1;    % L1
    L3 = app.L3;    % a3
    L4 = app.L4;    % d4

    % ====== MTH relativas Hi (tal como las definiste) ======
    % H0: base -> marco 0 (q1)
    H0 = [ c1  0  s1   0;
           s1  0 -c1   0;
            0  1   0  d1;
            0  0   0   1];

    % H1: 0 -> 1 (q2)
    H1 = [ c2 -s2  0  0;
           s2  c2  0  0;
            0   0  1  0;
            0   0  0  1];

    % H2: 1 -> 2 (q3 + pi/2, a3=L3)
    H2 = [ c3p  0  s3p  L3*c3p;
           s3p  0 -c3p  L3*s3p;
            0   1   0   0;
            0   0   0   1];

    % H3: 2 -> 3 (q4, d4=L4)
    H3 = [ c4  0 -s4   0;
           s4  0  c4   0;
            0 -1   0  L4;
            0  0   0   1];

    % H4: 3 -> 4 (q5)
    H4 = [ c5 -s5  0  0;
           s5  c5  0  0;
            0   0  1  0;
            0   0  0  1];

    % ====== Aplicar a los nodos jerárquicos ======
    T01 = H0;
T02 = T01*H1;
T03 = T02*H2;
T04 = T03*H3;
T05 = T04*H4;
    Rfix = makehgtform('xrotate',-pi/2);
    set(app.t0, 'Matrix', H0*Rfix);
    set(app.t1, 'Matrix', H1);
    set(app.t2, 'Matrix', H2);
    set(app.t3, 'Matrix', H3);
    set(app.t4, 'Matrix', H4);

    % t5 queda como efector final (identidad por ahora)
    set(app.t5, 'Matrix', eye(4));
    % --- DEBUG: dibuja los frames 0..5 para verificar DH ---
persistent hFrames
if ~isempty(hFrames)
    delete(hFrames); hFrames = [];
end

T01 = H0;
T02 = T01*H1;
T03 = T02*H2;
T04 = T03*H3;
T05 = T04*H4;

frames = cat(3, eye(4), T01, T02, T03, T04, T05); % 0..5

% Dibuja ejes (3 flechas por frame)
ax = app.UIAxes;
len = 0.03; % largo de las flechas
for k = 1:size(frames,3)
    T = frames(:,:,k);
    o = T(1:3,4).';
    x = (T(1:3,1)*len).'; y = (T(1:3,2)*len).'; z = (T(1:3,3)*len).';
hFrames(end+1) = quiver3(ax, o(1),o(2),o(3), x(1),x(2),x(3), 'AutoScale','off');
set(hFrames(end), 'Color', [1 0 0]); % X en rojo

hFrames(end+1) = quiver3(ax, o(1),o(2),o(3), y(1),y(2),y(3), 'AutoScale','off');
set(hFrames(end), 'Color', [0 1 0]); % Y en verde

hFrames(end+1) = quiver3(ax, o(1),o(2),o(3), z(1),z(2),z(3), 'AutoScale','off');
set(hFrames(end), 'Color', [0 0 1]); % Z en azul

end

    drawnow limitrate nocallbacks            
            
        end
    end
    
    methods (Access = public)
        
        function onAnySliderChanged(app, event)
            localUpdateRobot(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %--- Ajustes de los ejes 3D ---
    ax = app.UIAxes;
    hold(ax, 'on'); grid(ax, 'on');
    axis(ax, 'equal');
    axis(ax, [-0.3 0.3  -0.3 0.3  0 0.4]);   % ajusta según tamaño real
    xlabel(ax,'X'); ylabel(ax,'Y'); zlabel(ax,'Z');
    view(ax, 135, 25);
    rotate3d(ax,'on');

    %--- Carga de base fija (no se mueve) ---
    TRb = stlread('base_fija.STL');
    Fb = TRb.ConnectivityList;
    Vb = TRb.Points * app.scaleSTL;
    patch('Faces',Fb,'Vertices',Vb,'Parent',ax, ...
          'FaceColor',[0.6 0.6 0.6], ...
          'EdgeColor','none','FaceLighting','gouraud');

    %--- Nodos jerárquicos (cada eslabón "cuelga" del anterior) ---
    app.t0 = hgtransform('Parent', ax);
    app.t1 = hgtransform('Parent', app.t0);
    app.t2 = hgtransform('Parent', app.t1);
    app.t3 = hgtransform('Parent', app.t2);
    app.t4 = hgtransform('Parent', app.t3);
    app.t5 = hgtransform('Parent', app.t4);   % efector (sin STL, por ahora)

    %--- Carga de STL en cada nodo móvil ---
    app.p0 = localLoadSTL(app, 'J0_2.STL', app.t0);
    app.p1 = localLoadSTL(app, 'J1_nuevo.STL', app.t1);
    app.p2 = localLoadSTL(app, 'J2.STL', app.t2);
    app.p3 = localLoadSTL(app, 'J3.STL', app.t3);
    app.p4 = localLoadSTL(app, 'J4.STL', app.t4);
    % J5 no tiene STL (queda el nodo t5 libre para herramienta futura)

    %--- Luz y sombreado ---
    camlight(ax,'headlight'); lighting(ax,'gouraud');

    %--- Conectar sliders a un único callback común ---
    f = @(src,event) onAnySliderChanged(app, event);
    app.sQ1.ValueChangingFcn = f;  app.sQ1.ValueChangedFcn = f;
    app.sQ2.ValueChangingFcn = f;  app.sQ2.ValueChangedFcn = f;
    app.sQ3.ValueChangingFcn = f;  app.sQ3.ValueChangedFcn = f;
    app.sQ4.ValueChangingFcn = f;  app.sQ4.ValueChangedFcn = f;
    app.sQ5.ValueChangingFcn = f;  app.sQ5.ValueChangedFcn = f;

    %--- Posición inicial ---
    localUpdateRobot(app);
        end

        % Callback function: not associated with a component
        function conectarserialButtonPushed(app, event)
                    try
            % Abrir puerto serial
            app.s = serialport("COM3", 115200);
            configureTerminator(app.s, "LF");
            flush(app.s); % limpia buffer

            % Crear timer para leer datos cada 0.05s
            app.timerObj = timer('ExecutionMode','fixedSpacing',...
                                 'Period',0.05,...
                                 'TimerFcn', @(~,~) app.leerDatosSerial());
            start(app.timerObj);

            app.conectar_serial.Enable = 'off'; % deshabilitar botón para no abrir otra vez
        catch ME
            uialert(app.UIFigure, "Error al abrir el puerto serial: " + ME.message, "Error");
                    end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.sQ1SliderLabel = uilabel(app.UIFigure);
            app.sQ1SliderLabel.Text = 'sQ1';
            app.sQ1 = uislider(app.UIFigure);
            app.sQ1.Limits = [-3.14159265358979 3.14159265358979];
            app.sQ2SliderLabel = uilabel(app.UIFigure);
            app.sQ2SliderLabel.Text = 'sQ2';
            app.sQ2 = uislider(app.UIFigure);
            app.sQ2.Limits = [-3.14159265358979 3.14159265358979];
            app.sQ3SliderLabel = uilabel(app.UIFigure);
            app.sQ3SliderLabel.Text = 'sQ3';
            app.sQ3 = uislider(app.UIFigure);
            app.sQ3.Limits = [-3.14159265358979 3.14159265358979];
            app.sQ4SliderLabel = uilabel(app.UIFigure);
            app.sQ4SliderLabel.Text = 'sQ4';
            app.sQ4 = uislider(app.UIFigure);
            app.sQ4.Limits = [-3.14159265358979 3.14159265358979];
            app.sQ5SliderLabel = uilabel(app.UIFigure);
            app.sQ5SliderLabel.Text = 'sQ5';
            app.sQ5 = uislider(app.UIFigure);
            app.sQ5.Limits = [-3.14159265358979 3.14159265358979];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ambiente_virtual_ya_porfavor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end