classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        sQ5              matlab.ui.control.Slider
        Slider5Label     matlab.ui.control.Label
        sQ4              matlab.ui.control.Slider
        Slider4Label     matlab.ui.control.Label
        sQ3              matlab.ui.control.Slider
        Slider3Label     matlab.ui.control.Label
        sQ2              matlab.ui.control.Slider
        Slider2Label     matlab.ui.control.Label
        sQ1              matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        enviar_serial    matlab.ui.control.Button
        conectar_serial  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            s % objeto serialport
            timerObj % timer para leer datos periódicamente
            modelo = Ensamblaje_propio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: conectar_serial
        function conectar_serialButtonPushed(app, event)

        try
            % Abrir puerto serial
            app.s = serialport("COM3", 115200);
            configureTerminator(app.s, "LF");
            flush(app.s); % limpia buffer

            % Crear timer para leer datos cada 0.05s
            app.timerObj = timer('ExecutionMode','fixedSpacing',...
                                 'Period',0.05,...
                                 'TimerFcn', @(~,~) app.leerDatosSerial());
            start(app.timerObj);

            app.conectar_serial.Enable = 'off'; % deshabilitar botón para no abrir otra vez
        catch ME
            uialert(app.UIFigure, "Error al abrir el puerto serial: " + ME.message, "Error");
        end
        end

    % Función para leer datos serial y actualizar variables
    function leerDatosSerial(app)
        if app.s.NumBytesAvailable > 0
            try
                linea = readline(app.s); % lee línea completa terminada en LF
                % La línea debe tener 5 enteros separados por comas
                valores = sscanf(linea, '%d,%d,%d,%d,%d');
                if numel(valores) == 5
                    % Asignar variables en base MATLAB para Simulink
                    assignin('base', 'var1', valores(1));
                    assignin('base', 'var2', valores(2));
                    assignin('base', 'var3', valores(3));
                    assignin('base', 'var4', valores(4));
                    assignin('base', 'var5', valores(5));
                    set_param('Ensamblaje_propio', 'SimulationCommand', 'update')
                    % Opcional: actualizar algún label o log en UI
                    % app.LabelStatus.Text = sprintf('Datos recibidos: %d,%d,%d,%d,%d', valores);
                end
            catch
                % ignorar errores de parsing o líneas corruptas
            end
        end
    end

    % Callback para cerrar el puerto (puedes hacer un botón desconectar_serial)
    function desconectar_serialButtonPushed(app, event)
        if ~isempty(app.timerObj)
            stop(app.timerObj);
            delete(app.timerObj);
            app.timerObj = [];
        end
        if ~isempty(app.s)
            clear app.s;
            app.s = [];
        end
        app.conectar_serial.Enable = 'on'; % habilitar botón de conectar otra vez
        end

        % Button pushed function: enviar_serial
        function enviar_serialButtonPushed(app, event)
            function enviar_serialButtonPushed(app, event)
    % Asegúrate que el puerto serial está abierto
    if isempty(app.s) || ~isvalid(app.s)
        uialert(app.UIFigure, 'El puerto serial no está conectado.', 'Error');
        return;
    end

    % Obtener valores de los sliders (redondear si es necesario)
    v1 = round(app.sQ1.Value);
    v2 = round(app.sQ2.Value);
    v3 = round(app.sQ3.Value);
    v4 = round(app.sQ4.Value);
    v5 = round(app.sQ5.Value);

    % Formatear cadena
    msg = sprintf('%d,%d,%d,%d,%d\n', v1, v2, v3, v4, v5);

    % Enviar por serial
    try
        writeline(app.s, msg);
    catch ME
        uialert(app.UIFigure, 'Error al enviar datos por serial: ' + ME.message, 'Error');
    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.conectar_serial = uibutton(app.UIFigure, 'push');
            app.conectar_serial.ButtonPushedFcn = createCallbackFcn(app, @conectar_serialButtonPushed, true);
            app.conectar_serial.Text = 'Conectar Serial';
            app.enviar_serial = uibutton(app.UIFigure, 'push');
            app.enviar_serial.ButtonPushedFcn = createCallbackFcn(app, @enviar_serialButtonPushed, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.sQ1 = uislider(app.UIFigure);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.sQ2 = uislider(app.UIFigure);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.sQ3 = uislider(app.UIFigure);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.sQ4 = uislider(app.UIFigure);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.sQ5 = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end